classdef projectapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ResetButton                   matlab.ui.control.Button
        NmLabel                       matlab.ui.control.Label
        TorqueTrendInsightsButton     matlab.ui.control.Button
        PredictTorqueButton           matlab.ui.control.Button
        EditField_9                   matlab.ui.control.NumericEditField
        TorqueGauge                   matlab.ui.control.Gauge
        TorqueGaugeLabel              matlab.ui.control.Label
        EditField_8                   matlab.ui.control.NumericEditField
        EditField_7                   matlab.ui.control.NumericEditField
        EditField_6                   matlab.ui.control.NumericEditField
        EditField_5                   matlab.ui.control.NumericEditField
        EditField_4                   matlab.ui.control.NumericEditField
        EditField_3                   matlab.ui.control.NumericEditField
        EditField_2                   matlab.ui.control.NumericEditField
        PhaseVoltage1VKnob            matlab.ui.control.Knob
        PhaseVoltage1VKnobLabel       matlab.ui.control.Label
        CurrentAKnob                  matlab.ui.control.Knob
        CurrentAKnobLabel             matlab.ui.control.Label
        VoltageVKnob                  matlab.ui.control.Knob
        VoltageVKnobLabel             matlab.ui.control.Label
        PhaseCurrent3AKnob            matlab.ui.control.Knob
        PhaseCurrent3AKnobLabel       matlab.ui.control.Label
        PhaseCurrent2AKnob            matlab.ui.control.Knob
        PhaseCurrent2AKnobLabel       matlab.ui.control.Label
        PhaseCurrent1AKnob            matlab.ui.control.Knob
        PhaseCurrent1AKnobLabel       matlab.ui.control.Label
        PhaseVoltage3VKnob            matlab.ui.control.Knob
        PhaseVoltage3VKnobLabel       matlab.ui.control.Label
        PhaseVoltage2VKnob            matlab.ui.control.Knob
        PhaseVoltage2VKnobLabel       matlab.ui.control.Label
        EditField                     matlab.ui.control.NumericEditField
        SelectMotorTypeDropDown       matlab.ui.control.DropDown
        SelectMotorTypeDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        
    end

    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Value changed function: SelectMotorTypeDropDown
        function SelectMotorTypeDropDownValueChanged(app, event)
            value = app.SelectMotorTypeDropDown.Value;
                if strcmp(value, "Induction Motor")
        % Show Induction Motor Knobs
        app.PhaseVoltage1VKnob.Visible = true;
        app.PhaseVoltage2VKnob.Visible = true;
        app.PhaseVoltage3VKnob.Visible = true;
        app.PhaseCurrent1AKnob.Visible = true;
        app.PhaseCurrent2AKnob.Visible = true;
        app.PhaseCurrent3AKnob.Visible = true;
        app.PhaseVoltage1VKnobLabel.Visible = true;
        app.PhaseVoltage2VKnobLabel.Visible = true;
        app.PhaseVoltage3VKnobLabel.Visible = true;
        app.PhaseCurrent1AKnobLabel.Visible = true;
        app.PhaseCurrent2AKnobLabel.Visible = true;
        app.PhaseCurrent3AKnobLabel.Visible = true;
        % Displays
        app.EditField.Visible=true;
        app.EditField_2.Visible=true;
        app.EditField_3.Visible=true;
        app.EditField_6.Visible=true;
        app.EditField_7.Visible=true;
        app.EditField_8.Visible=true;

        % Hide DC Motor Knobs
        app.VoltageVKnob.Visible = false;
        app.CurrentAKnob.Visible = false;
        app.VoltageVKnobLabel.Visible=false;
        app.CurrentAKnobLabel.Visible=false;
        % Displays
        app.EditField_4.Visible=false;
        app.EditField_5.Visible=false;

    elseif strcmp(value, "DC Motor")
        % Show DC Motor Knobs 
        app.VoltageVKnob.Visible = true;
        app.CurrentAKnob.Visible = true;
        %and labels
        app.VoltageVKnobLabel.Visible=true;
        app.CurrentAKnobLabel.Visible=true;
        % Displays
        app.EditField_4.Visible=true;
        app.EditField_5.Visible=true;


        % Hide Induction Motor Knobs
        app.PhaseVoltage1VKnob.Visible = false;
        app.PhaseVoltage2VKnob.Visible = false;
        app.PhaseVoltage3VKnob.Visible = false;
        app.PhaseCurrent1AKnob.Visible = false;
        app.PhaseCurrent2AKnob.Visible = false;
        app.PhaseCurrent3AKnob.Visible = false;
        %and labels
        app.PhaseVoltage1VKnobLabel.Visible = false;
        app.PhaseVoltage2VKnobLabel.Visible = false;
        app.PhaseVoltage3VKnobLabel.Visible = false;
        app.PhaseCurrent1AKnobLabel.Visible = false;
        app.PhaseCurrent2AKnobLabel.Visible = false;
        app.PhaseCurrent3AKnobLabel.Visible = false;
        % Displays
        app.EditField.Visible=false;
        app.EditField_2.Visible=false;
        app.EditField_3.Visible=false;
        app.EditField_6.Visible=false;
        app.EditField_7.Visible=false;
        app.EditField_8.Visible=false;


                end


        end

        % Value changing function: PhaseVoltage1VKnob
        function PhaseVoltage1VKnobValueChanging(app, event)
            changingValue = event.Value;
            app.EditField.Value=changingValue;
        end

        % Value changing function: PhaseVoltage2VKnob
        function PhaseVoltage2VKnobValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_2.Value=changingValue;
        end

        % Value changing function: PhaseVoltage3VKnob
        function PhaseVoltage3VKnobValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_3.Value=changingValue;
        end

        % Value changing function: PhaseCurrent1AKnob
        function PhaseCurrent1AKnobValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_6.Value=changingValue;
        end

        % Value changing function: PhaseCurrent2AKnob
        function PhaseCurrent2AKnobValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_7.Value=changingValue;
        end

        % Value changing function: PhaseCurrent3AKnob
        function PhaseCurrent3AKnobValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_8.Value=changingValue;
        end

        % Value changing function: VoltageVKnob
        function VoltageVKnobValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_4.Value=changingValue;
        end

        % Value changing function: CurrentAKnob
        function CurrentAKnobValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_5.Value=changingValue;
        end

        % Button pushed function: PredictTorqueButton
        function PredictTorqueButtonPushed(app, event)
 selectedMotor = app.SelectMotorTypeDropDown.Value;

    if strcmp(selectedMotor, "Induction Motor")
        % Load model
        load('InductionMotorTorqueNet.mat', 'net');

        % Get inputs
        V1 = app.PhaseVoltage1VKnob.Value;
        V2 = app.PhaseVoltage2VKnob.Value;
        V3 = app.PhaseVoltage3VKnob.Value;
        I1 = app.PhaseCurrent1AKnob.Value;
        I2 = app.PhaseCurrent2AKnob.Value;
        I3 = app.PhaseCurrent3AKnob.Value;

        % Predict
        inputVec = [V1; V2; V3; I1; I2; I3];
        torque = net(inputVec);

    elseif strcmp(selectedMotor, "DC Motor")
        % Load model
        load('DCMotorTorqueNet.mat', 'net');

        % Get inputs
        V = app.VoltageVKnob.Value;
        I = app.CurrentAKnob.Value;

        % Predict
        inputVec = [V; I];
        torque = net(inputVec);
    end

    % Update UI
    app.TorqueGauge.Value = torque;
    app.EditField_9.Value = torque;
        end

        % Button pushed function: TorqueTrendInsightsButton
        function TorqueTrendInsightsButtonPushed(app, event)
            showTorqueTrendsStatic(app.SelectMotorTypeDropDown.Value);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.EditField_9.Value=0;
            app.TorqueGauge.Value=0;
            if strcmp(app.SelectMotorTypeDropDown.Value,"Induction Motor")
            app.PhaseVoltage1VKnob.Value=110;
            app.PhaseVoltage2VKnob.Value=110;
            app.PhaseVoltage3VKnob.Value=110;
            app.PhaseCurrent1AKnob.Value=5;
            app.PhaseCurrent2AKnob.Value=5;
            app.PhaseCurrent3AKnob.Value=5;
            app.EditField.Value=110;
            app.EditField_2.Value=110;
            app.EditField_3.Value=110;
            app.EditField_6.Value=5;
            app.EditField_7.Value=5;
            app.EditField_8.Value=5;
            end
           
        if strcmp(app.SelectMotorTypeDropDown.Value , "DC Motor")
            app.VoltageVKnob.Value=90;
            app.CurrentAKnob.Value=2;
            app.EditField_4.Value=90;
            app.EditField_5.Value=2;
       
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectMotorTypeDropDownLabel = uilabel(app.UIFigure);
            app.SelectMotorTypeDropDownLabel.Text = 'Select Motor Type';
            app.SelectMotorTypeDropDown = uidropdown(app.UIFigure);
            app.SelectMotorTypeDropDown.Items = {'Induction Motor', 'DC Motor'};
            app.SelectMotorTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectMotorTypeDropDownValueChanged, true);
            app.SelectMotorTypeDropDown.Value = 'Induction Motor';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Value = 110;
            app.PhaseVoltage2VKnobLabel = uilabel(app.UIFigure);
            app.PhaseVoltage2VKnobLabel.Text = 'Phase Voltage 2 (V)';
            app.PhaseVoltage2VKnob = uiknob(app.UIFigure, 'continuous');
            app.PhaseVoltage2VKnob.Limits = [110 265];
            app.PhaseVoltage2VKnob.ValueChangingFcn = createCallbackFcn(app, @PhaseVoltage2VKnobValueChanging, true);
            app.PhaseVoltage2VKnob.Value = 110;
            app.PhaseVoltage3VKnobLabel = uilabel(app.UIFigure);
            app.PhaseVoltage3VKnobLabel.Text = 'Phase Voltage 3 (V)';
            app.PhaseVoltage3VKnob = uiknob(app.UIFigure, 'continuous');
            app.PhaseVoltage3VKnob.Limits = [110 265];
            app.PhaseVoltage3VKnob.ValueChangingFcn = createCallbackFcn(app, @PhaseVoltage3VKnobValueChanging, true);
            app.PhaseVoltage3VKnob.Value = 110;
            app.PhaseCurrent1AKnobLabel = uilabel(app.UIFigure);
            app.PhaseCurrent1AKnobLabel.Text = 'Phase Current 1 (A)';
            app.PhaseCurrent1AKnob = uiknob(app.UIFigure, 'continuous');
            app.PhaseCurrent1AKnob.Limits = [5 20];
            app.PhaseCurrent1AKnob.ValueChangingFcn = createCallbackFcn(app, @PhaseCurrent1AKnobValueChanging, true);
            app.PhaseCurrent1AKnob.Value = 5;
            app.PhaseCurrent2AKnobLabel = uilabel(app.UIFigure);
            app.PhaseCurrent2AKnobLabel.Text = 'Phase Current 2 (A)';
            app.PhaseCurrent2AKnob = uiknob(app.UIFigure, 'continuous');
            app.PhaseCurrent2AKnob.Limits = [5 20];
            app.PhaseCurrent2AKnob.ValueChangingFcn = createCallbackFcn(app, @PhaseCurrent2AKnobValueChanging, true);
            app.PhaseCurrent2AKnob.Value = 5;
            app.PhaseCurrent3AKnobLabel = uilabel(app.UIFigure);
            app.PhaseCurrent3AKnobLabel.Text = 'Phase Current 3 (A)';
            app.PhaseCurrent3AKnob = uiknob(app.UIFigure, 'continuous');
            app.PhaseCurrent3AKnob.Limits = [5 20];
            app.PhaseCurrent3AKnob.ValueChangingFcn = createCallbackFcn(app, @PhaseCurrent3AKnobValueChanging, true);
            app.PhaseCurrent3AKnob.Value = 5;
            app.VoltageVKnobLabel = uilabel(app.UIFigure);
            app.VoltageVKnobLabel.Visible = 'off';
            app.VoltageVKnobLabel.Text = 'Voltage (V)';
            app.VoltageVKnob = uiknob(app.UIFigure, 'continuous');
            app.VoltageVKnob.Limits = [90 230];
            app.VoltageVKnob.ValueChangingFcn = createCallbackFcn(app, @VoltageVKnobValueChanging, true);
            app.VoltageVKnob.Visible = 'off';
            app.VoltageVKnob.Value = 90;
            app.CurrentAKnobLabel = uilabel(app.UIFigure);
            app.CurrentAKnobLabel.Visible = 'off';
            app.CurrentAKnobLabel.Text = 'Current (A)';
            app.CurrentAKnob = uiknob(app.UIFigure, 'continuous');
            app.CurrentAKnob.Limits = [2 15];
            app.CurrentAKnob.ValueChangingFcn = createCallbackFcn(app, @CurrentAKnobValueChanging, true);
            app.CurrentAKnob.Visible = 'off';
            app.CurrentAKnob.Value = 2;
            app.PhaseVoltage1VKnobLabel = uilabel(app.UIFigure);
            app.PhaseVoltage1VKnobLabel.Text = 'Phase Voltage 1 (V)';
            app.PhaseVoltage1VKnob = uiknob(app.UIFigure, 'continuous');
            app.PhaseVoltage1VKnob.Limits = [110 265];
            app.PhaseVoltage1VKnob.ValueChangingFcn = createCallbackFcn(app, @PhaseVoltage1VKnobValueChanging, true);
            app.PhaseVoltage1VKnob.Value = 110;
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Value = 110;
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.Value = 110;
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.Visible = 'off';
            app.EditField_4.Value = 90;
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5.Visible = 'off';
            app.EditField_5.Value = 2;
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6.Value = 5;
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_7.Value = 5;
            app.EditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_8.Value = 5;
            app.TorqueGaugeLabel = uilabel(app.UIFigure);
            app.TorqueGaugeLabel.Text = 'Torque';
            app.TorqueGauge = uigauge(app.UIFigure, 'circular');
            app.TorqueGauge.Limits = [0 80];
            app.TorqueGauge.MajorTicks = [0 20 40 60 80];
            app.EditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.PredictTorqueButton = uibutton(app.UIFigure, 'push');
            app.PredictTorqueButton.ButtonPushedFcn = createCallbackFcn(app, @PredictTorqueButtonPushed, true);
            app.PredictTorqueButton.Text = 'Predict Torque';
            app.TorqueTrendInsightsButton = uibutton(app.UIFigure, 'push');
            app.TorqueTrendInsightsButton.ButtonPushedFcn = createCallbackFcn(app, @TorqueTrendInsightsButtonPushed, true);
            app.TorqueTrendInsightsButton.Text = 'Torque Trend Insights';
            app.NmLabel = uilabel(app.UIFigure);
            app.NmLabel.Text = 'N/m';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = projectapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end